classdef SamplifierGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RegisterEditTab                 matlab.ui.container.Tab
        Register1EditFieldLabel         matlab.ui.control.Label
        Register1EditField              matlab.ui.control.NumericEditField
        Register2EditFieldLabel         matlab.ui.control.Label
        Register2EditField              matlab.ui.control.NumericEditField
        Register3EditFieldLabel         matlab.ui.control.Label
        Register3EditField              matlab.ui.control.NumericEditField
        Register4EditFieldLabel         matlab.ui.control.Label
        Register4EditField              matlab.ui.control.NumericEditField
        Register5EditFieldLabel         matlab.ui.control.Label
        Register5EditField              matlab.ui.control.NumericEditField
        Register6EditFieldLabel         matlab.ui.control.Label
        Register6EditField              matlab.ui.control.NumericEditField
        Register7EditFieldLabel         matlab.ui.control.Label
        Register7EditField              matlab.ui.control.NumericEditField
        Register8EditFieldLabel         matlab.ui.control.Label
        Register8EditField              matlab.ui.control.NumericEditField
        Register9EditFieldLabel         matlab.ui.control.Label
        Register9EditField              matlab.ui.control.NumericEditField
        Register10EditFieldLabel        matlab.ui.control.Label
        Register10EditField             matlab.ui.control.NumericEditField
        Register11EditField_2Label      matlab.ui.control.Label
        Register11EditField_2           matlab.ui.control.NumericEditField
        Register12EditField_2Label      matlab.ui.control.Label
        Register12EditField_2           matlab.ui.control.NumericEditField
        Register13EditField_2Label      matlab.ui.control.Label
        Register13EditField_2           matlab.ui.control.NumericEditField
        Register14EditFieldLabel        matlab.ui.control.Label
        Register14EditField             matlab.ui.control.NumericEditField
        Register15EditFieldLabel        matlab.ui.control.Label
        Register15EditField             matlab.ui.control.NumericEditField
        Register16EditFieldLabel        matlab.ui.control.Label
        Register16EditField             matlab.ui.control.NumericEditField
        Register17EditFieldLabel        matlab.ui.control.Label
        Register17EditField             matlab.ui.control.NumericEditField
        Register18EditFieldLabel        matlab.ui.control.Label
        Register18EditField             matlab.ui.control.NumericEditField
        Register19EditFieldLabel        matlab.ui.control.Label
        Register19EditField             matlab.ui.control.NumericEditField
        Register20EditFieldLabel        matlab.ui.control.Label
        Register20EditField             matlab.ui.control.NumericEditField
        Register21EditFieldLabel        matlab.ui.control.Label
        Register21EditField             matlab.ui.control.NumericEditField
        ReadRegsiter1EditFieldLabel     matlab.ui.control.Label
        ReadRegsiter1EditField          matlab.ui.control.NumericEditField
        ReadRegsiter2EditFieldLabel     matlab.ui.control.Label
        ReadRegsiter2EditField          matlab.ui.control.NumericEditField
        ReadRegsiter3EditFieldLabel     matlab.ui.control.Label
        ReadRegsiter3EditField          matlab.ui.control.NumericEditField
        ReadRegsiter4EditFieldLabel     matlab.ui.control.Label
        ReadRegsiter4EditField          matlab.ui.control.NumericEditField
        WriteModifiedRegsButton         matlab.ui.control.Button
        RereadAllButton                 matlab.ui.control.Button
        RButton                         matlab.ui.control.Button
        RButton_2                       matlab.ui.control.Button
        RButton_3                       matlab.ui.control.Button
        RButton_4                       matlab.ui.control.Button
        RButton_5                       matlab.ui.control.Button
        RButton_6                       matlab.ui.control.Button
        RButton_7                       matlab.ui.control.Button
        RButton_8                       matlab.ui.control.Button
        RButton_9                       matlab.ui.control.Button
        ConfigureTab                    matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        RCV_COR1Tab                     matlab.ui.container.Tab
        GridLayout_2                    matlab.ui.container.GridLayout
        Label_8                         matlab.ui.control.Label
        BitsLengthLabel_2               matlab.ui.control.Label
        BinaryLabel_2                   matlab.ui.control.Label
        DescriptionLabel_2              matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        bitcodefor8bitcorrelatorlowersectionforothersEditFieldLabel  matlab.ui.control.Label
        LowerCorrelatorCodeEditField    matlab.ui.control.EditField
        WriteButton_2                   matlab.ui.control.Button
        DiscardChangesButton_2          matlab.ui.control.Button
        UpperPartof15bitcodeforall15bitcorrelatorsEditFieldLabel  matlab.ui.control.Label
        UpperCorrelatorCodeEditField    matlab.ui.control.EditField
        RCV_COR2Tab                     matlab.ui.container.Tab
        GridLayout_3                    matlab.ui.container.GridLayout
        Label_10                        matlab.ui.control.Label
        BitsLengthLabel_3               matlab.ui.control.Label
        BinaryLabel_3                   matlab.ui.control.Label
        DescriptionLabel_3              matlab.ui.control.Label
        WriteButton_3                   matlab.ui.control.Button
        DiscardChangesButton_3          matlab.ui.control.Button
        Upper15bitcodefor31bitcorrelatorEditFieldLabel  matlab.ui.control.Label
        Upper15bitcodefor31bitcorrelatorEditField  matlab.ui.control.EditField
        RCV_AGOC1Tab                    matlab.ui.container.Tab
        GridLayout_4                    matlab.ui.container.GridLayout
        Label_11                        matlab.ui.control.Label
        BitsLengthLabel_4               matlab.ui.control.Label
        BinaryLabel_4                   matlab.ui.control.Label
        DescriptionLabel_4              matlab.ui.control.Label
        Fine1sThresholdEditFieldLabel   matlab.ui.control.Label
        Fine1sThresholdEditField        matlab.ui.control.EditField
        Label_12                        matlab.ui.control.Label
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.EditField
        WriteButton_4                   matlab.ui.control.Button
        DiscardChangesButton_4          matlab.ui.control.Button
        RCV_AGOC2Tab                    matlab.ui.container.Tab
        GridLayout_5                    matlab.ui.container.GridLayout
        BitsLengthLabel_5               matlab.ui.control.Label
        BinaryLabel_5                   matlab.ui.control.Label
        DescriptionLabel_5              matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Sequential1sThresholdEditFieldLabel  matlab.ui.control.Label
        Sequential1sThresholdEditField  matlab.ui.control.EditField
        Label_15                        matlab.ui.control.Label
        Moderate1sThresholdEditFieldLabel  matlab.ui.control.Label
        Moderate1sThresholdEditField    matlab.ui.control.EditField
        WriteButton_5                   matlab.ui.control.Button
        DiscardChangesButton_5          matlab.ui.control.Button
        RCV_AGOC3Tab                    matlab.ui.container.Tab
        GridLayout_6                    matlab.ui.container.GridLayout
        Label_16                        matlab.ui.control.Label
        BitsLengthLabel_6               matlab.ui.control.Label
        BinaryLabel_6                   matlab.ui.control.Label
        DescriptionLabel_6              matlab.ui.control.Label
        BoundaryFlagThresholdEditFieldLabel  matlab.ui.control.Label
        BoundaryFlagThresholdEditField  matlab.ui.control.EditField
        Label_17                        matlab.ui.control.Label
        Sequential0sThresholdEditFieldLabel  matlab.ui.control.Label
        Sequential0sThresholdEditField  matlab.ui.control.EditField
        WriteButton_6                   matlab.ui.control.Button
        DiscardChangesButton_6          matlab.ui.control.Button
        RCV_AGOC4Tab                    matlab.ui.container.Tab
        GridLayout_7                    matlab.ui.container.GridLayout
        Label_18                        matlab.ui.control.Label
        BitsLengthLabel_7               matlab.ui.control.Label
        BinaryLabel_7                   matlab.ui.control.Label
        DescriptionLabel_7              matlab.ui.control.Label
        MaxGainLimitEditFieldLabel      matlab.ui.control.Label
        MaxGainLimitEditField           matlab.ui.control.EditField
        Label_19                        matlab.ui.control.Label
        BottomComparatorThresholdEditFieldLabel  matlab.ui.control.Label
        BottomComparatorThresholdEditField  matlab.ui.control.EditField
        WriteButton_7                   matlab.ui.control.Button
        DiscardChangesButton_7          matlab.ui.control.Button
        Label_20                        matlab.ui.control.Label
        TopComparatorThresholdEditFieldLabel  matlab.ui.control.Label
        TopComparatorThresholdEditField  matlab.ui.control.EditField
        RCV_AGOC5Tab                    matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        BitsLengthLabel                 matlab.ui.control.Label
        BinaryLabel                     matlab.ui.control.Label
        DescriptionLabel                matlab.ui.control.Label
        InaccessibleEditFieldLabel      matlab.ui.control.Label
        InaccessibleEditField           matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        GainResetEditFieldLabel         matlab.ui.control.Label
        GainResetEditField              matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
        OffsetResetEditFieldLabel       matlab.ui.control.Label
        OffsetResetEditField            matlab.ui.control.EditField
        Label_4                         matlab.ui.control.Label
        GainMuxEditFieldLabel           matlab.ui.control.Label
        GainMuxEditField                matlab.ui.control.EditField
        Label_5                         matlab.ui.control.Label
        GainEnableEditFieldLabel        matlab.ui.control.Label
        GainEnableEditField             matlab.ui.control.EditField
        Label_6                         matlab.ui.control.Label
        OffsetEnableEditFieldLabel      matlab.ui.control.Label
        OffsetEnableEditField           matlab.ui.control.EditField
        Label_7                         matlab.ui.control.Label
        AGOCResetEditFieldLabel         matlab.ui.control.Label
        AGOCResetEditField              matlab.ui.control.EditField
        WriteButton                     matlab.ui.control.Button
        DiscardChangesButton            matlab.ui.control.Button
        RCV_MISCTab                     matlab.ui.container.Tab
        GridLayout_8                    matlab.ui.container.GridLayout
        Label_21                        matlab.ui.control.Label
        BitsLengthLabel_8               matlab.ui.control.Label
        BinaryLabel_8                   matlab.ui.control.Label
        DescriptionLabel_8              matlab.ui.control.Label
        cor_selEditFieldLabel           matlab.ui.control.Label
        cor_selEditField                matlab.ui.control.EditField
        Label_22                        matlab.ui.control.Label
        AGOC_selEditFieldLabel          matlab.ui.control.Label
        AGOC_selEditField               matlab.ui.control.EditField
        WriteButton_8                   matlab.ui.control.Button
        DiscardChangesButton_8          matlab.ui.control.Button
        Label_23                        matlab.ui.control.Label
        en_cor_selEditFieldLabel        matlab.ui.control.Label
        en_cor_selEditField             matlab.ui.control.EditField
        Label_24                        matlab.ui.control.Label
        CorrelatorErrorThreshold40for31bit30otherwiseEditFieldLabel  matlab.ui.control.Label
        CorrelatorErrorThreshold40for31bit30otherwiseEditField  matlab.ui.control.EditField
        RCV_TMG1Tab                     matlab.ui.container.Tab
        GridLayout_9                    matlab.ui.container.GridLayout
        Label_25                        matlab.ui.control.Label
        BitsLengthLabel_9               matlab.ui.control.Label
        BinaryLabel_9                   matlab.ui.control.Label
        DescriptionLabel_9              matlab.ui.control.Label
        TimingResetEditFieldLabel       matlab.ui.control.Label
        TimingResetEditField            matlab.ui.control.EditField
        Label_26                        matlab.ui.control.Label
        ShowClockValueEditFieldLabel    matlab.ui.control.Label
        ShowClockValueEditField         matlab.ui.control.EditField
        WriteButton_9                   matlab.ui.control.Button
        DiscardChangesButton_9          matlab.ui.control.Button
        RCV_TMG2Tab                     matlab.ui.container.Tab
        GridLayout_10                   matlab.ui.container.GridLayout
        Label_27                        matlab.ui.control.Label
        BitsLengthLabel_10              matlab.ui.control.Label
        BinaryLabel_10                  matlab.ui.control.Label
        DescriptionLabel_10             matlab.ui.control.Label
        AnalogActivateValueEditFieldLabel  matlab.ui.control.Label
        AnalogActivateValueEditField    matlab.ui.control.EditField
        WriteButton_10                  matlab.ui.control.Button
        DiscardChangesButton_10         matlab.ui.control.Button
        RCV_TMG3Tab                     matlab.ui.container.Tab
        GridLayout_11                   matlab.ui.container.GridLayout
        Label_28                        matlab.ui.control.Label
        BitsLengthLabel_11              matlab.ui.control.Label
        BinaryLabel_11                  matlab.ui.control.Label
        DescriptionLabel_11             matlab.ui.control.Label
        AnalogOnValueEditFieldLabel     matlab.ui.control.Label
        AnalogOnValueEditField          matlab.ui.control.EditField
        WriteButton_11                  matlab.ui.control.Button
        DiscardChangesButton_11         matlab.ui.control.Button
        RCV_TMG4Tab                     matlab.ui.container.Tab
        GridLayout_12                   matlab.ui.container.GridLayout
        Label_29                        matlab.ui.control.Label
        BitsLengthLabel_12              matlab.ui.control.Label
        BinaryLabel_12                  matlab.ui.control.Label
        DescriptionLabel_12             matlab.ui.control.Label
        RFOnValueEditFieldLabel         matlab.ui.control.Label
        RFOnValueEditField              matlab.ui.control.EditField
        WriteButton_12                  matlab.ui.control.Button
        DiscardChangesButton_12         matlab.ui.control.Button
        RCV_TMG5Tab                     matlab.ui.container.Tab
        GridLayout_13                   matlab.ui.container.GridLayout
        Label_30                        matlab.ui.control.Label
        BitsLengthLabel_13              matlab.ui.control.Label
        BinaryLabel_13                  matlab.ui.control.Label
        DescriptionLabel_13             matlab.ui.control.Label
        AnalogOffValueEditFieldLabel    matlab.ui.control.Label
        AnalogOffValueEditField         matlab.ui.control.EditField
        WriteButton_13                  matlab.ui.control.Button
        DiscardChangesButton_13         matlab.ui.control.Button
        RCV_TMG6Tab                     matlab.ui.container.Tab
        GridLayout_14                   matlab.ui.container.GridLayout
        Label_31                        matlab.ui.control.Label
        BitsLengthLabel_14              matlab.ui.control.Label
        BinaryLabel_14                  matlab.ui.control.Label
        DescriptionLabel_14             matlab.ui.control.Label
        RFOffValueEditFieldLabel        matlab.ui.control.Label
        RFOffValueEditField             matlab.ui.control.EditField
        WriteButton_14                  matlab.ui.control.Button
        DiscardChangesButton_14         matlab.ui.control.Button
        RCV_CLK1Tab                     matlab.ui.container.Tab
        GridLayout_15                   matlab.ui.container.GridLayout
        Label_32                        matlab.ui.control.Label
        BitsLengthLabel_15              matlab.ui.control.Label
        BinaryLabel_15                  matlab.ui.control.Label
        DescriptionLabel_15             matlab.ui.control.Label
        csub_sel_10throughcsub_sel_0EditFieldLabel  matlab.ui.control.Label
        csub_sel_10throughcsub_sel_0EditField  matlab.ui.control.EditField
        WriteButton_15                  matlab.ui.control.Button
        DiscardChangesButton_15         matlab.ui.control.Button
        RCV_CLK2Tab                     matlab.ui.container.Tab
        GridLayout_16                   matlab.ui.container.GridLayout
        Label_33                        matlab.ui.control.Label
        BitsLengthLabel_16              matlab.ui.control.Label
        BinaryLabel_16                  matlab.ui.control.Label
        DescriptionLabel_16             matlab.ui.control.Label
        DIV_11throughDIV_0EditFieldLabel  matlab.ui.control.Label
        DIV_11throughDIV_0EditField     matlab.ui.control.EditField
        WriteButton_16                  matlab.ui.control.Button
        DiscardChangesButton_16         matlab.ui.control.Button
        RCV_CLK3Tab                     matlab.ui.container.Tab
        GridLayout_17                   matlab.ui.container.GridLayout
        Label_34                        matlab.ui.control.Label
        BitsLengthLabel_17              matlab.ui.control.Label
        BinaryLabel_17                  matlab.ui.control.Label
        DescriptionLabel_17             matlab.ui.control.Label
        HVT_SEL_0throughHVT_SEL_9EditFieldLabel  matlab.ui.control.Label
        HVT_SEL_0throughHVT_SEL_9EditField  matlab.ui.control.EditField
        WriteButton_17                  matlab.ui.control.Button
        DiscardChangesButton_17         matlab.ui.control.Button
        RCV_CLK4Tab                     matlab.ui.container.Tab
        GridLayout_18                   matlab.ui.container.GridLayout
        Label_35                        matlab.ui.control.Label
        BitsLengthLabel_18              matlab.ui.control.Label
        BinaryLabel_18                  matlab.ui.control.Label
        DescriptionLabel_18             matlab.ui.control.Label
        OUT_SEL_1OUT_SEL_2EditFieldLabel  matlab.ui.control.Label
        OUT_SEL_1OUT_SEL_2EditField     matlab.ui.control.EditField
        WriteButton_18                  matlab.ui.control.Button
        DiscardChangesButton_18         matlab.ui.control.Button
        Label_36                        matlab.ui.control.Label
        EXT_CLK_IN_SELEditFieldLabel    matlab.ui.control.Label
        EXT_CLK_IN_SELEditField         matlab.ui.control.EditField
        Label_37                        matlab.ui.control.Label
        BYPASS_PREDIVEditFieldLabel     matlab.ui.control.Label
        BYPASS_PREDIVEditField          matlab.ui.control.EditField
        Label_38                        matlab.ui.control.Label
        RESET_DIVIDEREditFieldLabel     matlab.ui.control.Label
        RESET_DIVIDEREditField          matlab.ui.control.EditField
        Label_39                        matlab.ui.control.Label
        LVT_SEL_9throughLVT_SEL_0EditFieldLabel  matlab.ui.control.Label
        LVT_SEL_9throughLVT_SEL_0EditField  matlab.ui.control.EditField
        RCV_CLK5Tab                     matlab.ui.container.Tab
        GridLayout_19                   matlab.ui.container.GridLayout
        Label_40                        matlab.ui.control.Label
        BitsLengthLabel_19              matlab.ui.control.Label
        BinaryLabel_19                  matlab.ui.control.Label
        DescriptionLabel_19             matlab.ui.control.Label
        RVT_SEL_9throughRVT_SEL_0EditFieldLabel  matlab.ui.control.Label
        RVT_SEL_9throughRVT_SEL_0EditField  matlab.ui.control.EditField
        WriteButton_19                  matlab.ui.control.Button
        DiscardChangesButton_19         matlab.ui.control.Button
        Label_41                        matlab.ui.control.Label
        VREF2_SELEditFieldLabel         matlab.ui.control.Label
        VREF2_SELEditField              matlab.ui.control.EditField
        Label_42                        matlab.ui.control.Label
        VREF1_SELEditFieldLabel         matlab.ui.control.Label
        VREF1_SELEditField              matlab.ui.control.EditField
        RCV_BBTab                       matlab.ui.container.Tab
        GridLayout_20                   matlab.ui.container.GridLayout
        Label_43                        matlab.ui.control.Label
        BitsLengthLabel_20              matlab.ui.control.Label
        BinaryLabel_20                  matlab.ui.control.Label
        DescriptionLabel_20             matlab.ui.control.Label
        Gain_SPIEditFieldLabel          matlab.ui.control.Label
        Gain_SPIEditField               matlab.ui.control.EditField
        WriteButton_20                  matlab.ui.control.Button
        DiscardChangesButton_20         matlab.ui.control.Button
        Label_44                        matlab.ui.control.Label
        Offset_SPIEditFieldLabel        matlab.ui.control.Label
        Offset_SPIEditField             matlab.ui.control.EditField
        RCV_RF1Tab                      matlab.ui.container.Tab
        GridLayout_21                   matlab.ui.container.GridLayout
        Label_45                        matlab.ui.control.Label
        BitsLengthLabel_21              matlab.ui.control.Label
        BinaryLabel_21                  matlab.ui.control.Label
        DescriptionLabel_21             matlab.ui.control.Label
        ComparatorPadMuxEditFieldLabel  matlab.ui.control.Label
        ComparatorPadMuxEditField       matlab.ui.control.EditField
        WriteButton_21                  matlab.ui.control.Button
        DiscardChangesButton_21         matlab.ui.control.Button
        Label_46                        matlab.ui.control.Label
        TimingPadMuxEditFieldLabel      matlab.ui.control.Label
        TimingPadMuxEditField           matlab.ui.control.EditField
        Label_47                        matlab.ui.control.Label
        VCD0EditFieldLabel              matlab.ui.control.Label
        VCD0EditField                   matlab.ui.control.EditField
        Label_48                        matlab.ui.control.Label
        VCD1EditFieldLabel              matlab.ui.control.Label
        VCD1EditField                   matlab.ui.control.EditField
        Label_49                        matlab.ui.control.Label
        VCD2EditFieldLabel              matlab.ui.control.Label
        VCD2EditField                   matlab.ui.control.EditField
        Label_50                        matlab.ui.control.Label
        LPF_ENEditFieldLabel            matlab.ui.control.Label
        LPF_ENEditField                 matlab.ui.control.EditField
        Label_51                        matlab.ui.control.Label
        BB_IN_ENEditFieldLabel          matlab.ui.control.Label
        BB_IN_ENEditField               matlab.ui.control.EditField
        Label_52                        matlab.ui.control.Label
        BB_OUT_ENEditFieldLabel         matlab.ui.control.Label
        BB_OUT_ENEditField              matlab.ui.control.EditField
        Label_53                        matlab.ui.control.Label
        CAP_5throughCAP_0EditFieldLabel  matlab.ui.control.Label
        CAP_5throughCAP_0EditField      matlab.ui.control.EditField
        RCV_RF2Tab                      matlab.ui.container.Tab
        GridLayout_22                   matlab.ui.container.GridLayout
        Label_54                        matlab.ui.control.Label
        BitsLengthLabel_22              matlab.ui.control.Label
        BinaryLabel_22                  matlab.ui.control.Label
        DescriptionLabel_22             matlab.ui.control.Label
        WriteButton_22                  matlab.ui.control.Button
        DiscardChangesButton_22         matlab.ui.control.Button
        FLOATINGEditFieldLabel          matlab.ui.control.Label
        FLOATINGEditField               matlab.ui.control.EditField
        WRT_BBTab                       matlab.ui.container.Tab
        GridLayout_23                   matlab.ui.container.GridLayout
        Label_55                        matlab.ui.control.Label
        BitsLengthLabel_23              matlab.ui.control.Label
        BinaryLabel_23                  matlab.ui.control.Label
        DescriptionLabel_23             matlab.ui.control.Label
        WriteButton_23                  matlab.ui.control.Button
        DiscardChangesButton_23         matlab.ui.control.Button
        FLOATINGEditField_2Label        matlab.ui.control.Label
        FLOATINGEditField_2             matlab.ui.control.EditField
        WRT_RF1Tab                      matlab.ui.container.Tab
        GridLayout_25                   matlab.ui.container.GridLayout
        Label_57                        matlab.ui.control.Label
        BitsLengthLabel_25              matlab.ui.control.Label
        BinaryLabel_25                  matlab.ui.control.Label
        DescriptionLabel_25             matlab.ui.control.Label
        ComparatorPadMuxEditField_2Label  matlab.ui.control.Label
        ComparatorPadMuxEditField_2     matlab.ui.control.EditField
        WriteButton_25                  matlab.ui.control.Button
        DiscardChangesButton_25         matlab.ui.control.Button
        Label_58                        matlab.ui.control.Label
        TimingPadMuxEditField_2Label    matlab.ui.control.Label
        TimingPadMuxEditField_2         matlab.ui.control.EditField
        Label_59                        matlab.ui.control.Label
        VCD0EditField_2Label            matlab.ui.control.Label
        VCD0EditField_2                 matlab.ui.control.EditField
        Label_60                        matlab.ui.control.Label
        VCD1EditField_2Label            matlab.ui.control.Label
        VCD1EditField_2                 matlab.ui.control.EditField
        Label_61                        matlab.ui.control.Label
        VCD2EditField_2Label            matlab.ui.control.Label
        VCD2EditField_2                 matlab.ui.control.EditField
        Label_62                        matlab.ui.control.Label
        LPF_ENEditField_2Label          matlab.ui.control.Label
        LPF_ENEditField_2               matlab.ui.control.EditField
        Label_63                        matlab.ui.control.Label
        BB_IN_ENEditField_2Label        matlab.ui.control.Label
        BB_IN_ENEditField_2             matlab.ui.control.EditField
        Label_64                        matlab.ui.control.Label
        BB_OUT_ENEditField_2Label       matlab.ui.control.Label
        BB_OUT_ENEditField_2            matlab.ui.control.EditField
        Label_65                        matlab.ui.control.Label
        CAP_5throughCAP_0EditField_2Label  matlab.ui.control.Label
        CAP_5throughCAP_0EditField_2    matlab.ui.control.EditField
        WRT_RF2Tab                      matlab.ui.container.Tab
        GridLayout_24                   matlab.ui.container.GridLayout
        Label_56                        matlab.ui.control.Label
        BitsLengthLabel_24              matlab.ui.control.Label
        BinaryLabel_24                  matlab.ui.control.Label
        DescriptionLabel_24             matlab.ui.control.Label
        WriteButton_24                  matlab.ui.control.Button
        DiscardChangesButton_24         matlab.ui.control.Button
        FLOATINGEditField_3Label        matlab.ui.control.Label
        FLOATINGEditField_3             matlab.ui.control.EditField
        WRT_AGOCTab                     matlab.ui.container.Tab
        GridLayout_26                   matlab.ui.container.GridLayout
        Label_66                        matlab.ui.control.Label
        BitsLengthLabel_26              matlab.ui.control.Label
        BinaryLabel_26                  matlab.ui.control.Label
        DescriptionLabel_26             matlab.ui.control.Label
        BoundaryFlagEditFieldLabel      matlab.ui.control.Label
        BoundaryFlagEditField           matlab.ui.control.EditField
        WriteButton_26                  matlab.ui.control.Button
        DiscardChangesButton_26         matlab.ui.control.Button
        Label_67                        matlab.ui.control.Label
        BottomComparatorInEditFieldLabel  matlab.ui.control.Label
        BottomComparatorInEditField     matlab.ui.control.EditField
        Label_68                        matlab.ui.control.Label
        TopComparatorInEditFieldLabel   matlab.ui.control.Label
        TopComparatorInEditField        matlab.ui.control.EditField
        Label_69                        matlab.ui.control.Label
        BottomComparatorOffsetEditFieldLabel  matlab.ui.control.Label
        BottomComparatorOffsetEditField  matlab.ui.control.EditField
        Label_70                        matlab.ui.control.Label
        TopComparatorOffsetEditFieldLabel  matlab.ui.control.Label
        TopComparatorOffsetEditField    matlab.ui.control.EditField
        WRT_COR1Tab                     matlab.ui.container.Tab
        GridLayout_27                   matlab.ui.container.GridLayout
        Label_71                        matlab.ui.control.Label
        BitsLengthLabel_27              matlab.ui.control.Label
        BinaryLabel_27                  matlab.ui.control.Label
        DescriptionLabel_27             matlab.ui.control.Label
        Error1_15b_osx2EditFieldLabel   matlab.ui.control.Label
        Error1_15b_osx2EditField        matlab.ui.control.EditField
        WriteButton_27                  matlab.ui.control.Button
        DiscardChangesButton_27         matlab.ui.control.Button
        Label_72                        matlab.ui.control.Label
        Error0_15b_osx2EditFieldLabel   matlab.ui.control.Label
        Error0_15b_osx2EditField        matlab.ui.control.EditField
        Label_73                        matlab.ui.control.Label
        Error1_8bEditFieldLabel         matlab.ui.control.Label
        Error1_8bEditField              matlab.ui.control.EditField
        Label_74                        matlab.ui.control.Label
        Error0_8bEditFieldLabel         matlab.ui.control.Label
        Error0_8bEditField              matlab.ui.control.EditField
        WRT_COR2Tab                     matlab.ui.container.Tab
        GridLayout_28                   matlab.ui.container.GridLayout
        Label_75                        matlab.ui.control.Label
        BitsLengthLabel_28              matlab.ui.control.Label
        BinaryLabel_28                  matlab.ui.control.Label
        DescriptionLabel_28             matlab.ui.control.Label
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        WriteButton_28                  matlab.ui.control.Button
        DiscardChangesButton_28         matlab.ui.control.Button
        Label_76                        matlab.ui.control.Label
        EditField_2Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        Label_77                        matlab.ui.control.Label
        Error1_31bEditFieldLabel        matlab.ui.control.Label
        Error1_31bEditField             matlab.ui.control.EditField
        Label_78                        matlab.ui.control.Label
        Error0_31bEditFieldLabel        matlab.ui.control.Label
        Error0_31bEditField             matlab.ui.control.EditField
        Label_79                        matlab.ui.control.Label
        Error0_15b_osx1EditFieldLabel   matlab.ui.control.Label
        Error0_15b_osx1EditField        matlab.ui.control.EditField
    end

    
    properties (Access = public)
        a % Description
    end
    
    properties (Access = private)
        spi_device % Description
    end
    
    methods (Access = private)
        
        
        function output = assertStringLength(app, string, length)
            if strlength(string) ~= length
                uialert(app.UIFigure,'Entered bits do not match the register size','Invalid Bit Length')
                output = false;
            else
                output = true;
            end
        end
        
        function output = assertBinary(app, string)
            output = true;
            try
                bin2dec(string);
            catch
                uialert(app.UIFigure,'Entered data are not binary','Invalid Input');
                output = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~exist('app.a','var')
                app.a = arduino();
            end
            if ~exist('app.spi_device','var')
                app.spi_device = device(app.a, 'SPIChipSelectPin', 'D10');
            end
        end

        % Callback function
        function BlinkNumTimesEditFieldValueChanged(app, event)
            value = app.BlinkNumTimesEditField.Value;
            for idx = 1:value
                writeDigitalPin(app.a,'D9',1);
                pause(0.5);
                writeDigitalPin(app.a,'D9',0);
                pause(0.5);
            end
        end

        % Button pushed function: WriteButton_2
        function WriteButton_2Pushed(app, event)
            concat_string = strcat(app.UpperCorrelatorCodeEditField.Value, app.LowerCorrelatorCodeEditField.Value);
            if assertStringLength(app, concat_string, 16) && assertBinary(app, concat_string)
                disp(concat_string)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegisterEditTab = uitab(app.TabGroup);
            app.RegisterEditTab.Title = 'Register Edit';
            app.Register1EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register1EditFieldLabel.Text = 'Register 1';
            app.Register1EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register1EditField.ValueDisplayFormat = '%.0f';
            app.Register2EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register2EditFieldLabel.Text = 'Register 2';
            app.Register2EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register2EditField.ValueDisplayFormat = '%.0f';
            app.Register3EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register3EditFieldLabel.Text = 'Register 3';
            app.Register3EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register3EditField.ValueDisplayFormat = '%.0f';
            app.Register4EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register4EditFieldLabel.Text = 'Register 4';
            app.Register4EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register4EditField.ValueDisplayFormat = '%.0f';
            app.Register5EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register5EditFieldLabel.Text = 'Register 5';
            app.Register5EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register5EditField.ValueDisplayFormat = '%.0f';
            app.Register6EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register6EditFieldLabel.Text = 'Register 6';
            app.Register6EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register6EditField.ValueDisplayFormat = '%.0f';
            app.Register7EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register7EditFieldLabel.Text = 'Register 7';
            app.Register7EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register7EditField.ValueDisplayFormat = '%.0f';
            app.Register8EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register8EditFieldLabel.Text = 'Register 8';
            app.Register8EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register8EditField.ValueDisplayFormat = '%.0f';
            app.Register9EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register9EditFieldLabel.Text = 'Register 9';
            app.Register9EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register9EditField.ValueDisplayFormat = '%.0f';
            app.Register10EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register10EditFieldLabel.Text = 'Register 10';
            app.Register10EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register10EditField.ValueDisplayFormat = '%.0f';
            app.Register11EditField_2Label = uilabel(app.RegisterEditTab);
            app.Register11EditField_2Label.Text = 'Register 11';
            app.Register11EditField_2 = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register11EditField_2.ValueDisplayFormat = '%.0f';
            app.Register12EditField_2Label = uilabel(app.RegisterEditTab);
            app.Register12EditField_2Label.Text = 'Register 12';
            app.Register12EditField_2 = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register12EditField_2.ValueDisplayFormat = '%.0f';
            app.Register13EditField_2Label = uilabel(app.RegisterEditTab);
            app.Register13EditField_2Label.Text = 'Register 13';
            app.Register13EditField_2 = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register13EditField_2.ValueDisplayFormat = '%.0f';
            app.Register14EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register14EditFieldLabel.Text = 'Register 14';
            app.Register14EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register14EditField.ValueDisplayFormat = '%.0f';
            app.Register15EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register15EditFieldLabel.Text = 'Register 15';
            app.Register15EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register15EditField.ValueDisplayFormat = '%.0f';
            app.Register16EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register16EditFieldLabel.Text = 'Register 16';
            app.Register16EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register16EditField.ValueDisplayFormat = '%.0f';
            app.Register17EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register17EditFieldLabel.Text = 'Register 17';
            app.Register17EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register17EditField.ValueDisplayFormat = '%.0f';
            app.Register18EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register18EditFieldLabel.Text = 'Register 18';
            app.Register18EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register18EditField.ValueDisplayFormat = '%.0f';
            app.Register19EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register19EditFieldLabel.Text = 'Register 19';
            app.Register19EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register19EditField.ValueDisplayFormat = '%.0f';
            app.Register20EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register20EditFieldLabel.Text = 'Register 20';
            app.Register20EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register20EditField.ValueDisplayFormat = '%.0f';
            app.Register21EditFieldLabel = uilabel(app.RegisterEditTab);
            app.Register21EditFieldLabel.Text = 'Register 21';
            app.Register21EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.Register21EditField.ValueDisplayFormat = '%.0f';
            app.ReadRegsiter1EditFieldLabel = uilabel(app.RegisterEditTab);
            app.ReadRegsiter1EditFieldLabel.Text = 'Read Regsiter 1';
            app.ReadRegsiter1EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.ReadRegsiter1EditField.ValueDisplayFormat = '%.0f';
            app.ReadRegsiter1EditField.Editable = 'off';
            app.ReadRegsiter2EditFieldLabel = uilabel(app.RegisterEditTab);
            app.ReadRegsiter2EditFieldLabel.Text = 'Read Regsiter 2';
            app.ReadRegsiter2EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.ReadRegsiter2EditField.ValueDisplayFormat = '%.0f';
            app.ReadRegsiter2EditField.Editable = 'off';
            app.ReadRegsiter3EditFieldLabel = uilabel(app.RegisterEditTab);
            app.ReadRegsiter3EditFieldLabel.Text = 'Read Regsiter 3';
            app.ReadRegsiter3EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.ReadRegsiter3EditField.ValueDisplayFormat = '%.0f';
            app.ReadRegsiter3EditField.Editable = 'off';
            app.ReadRegsiter4EditFieldLabel = uilabel(app.RegisterEditTab);
            app.ReadRegsiter4EditFieldLabel.Text = 'Read Regsiter 4';
            app.ReadRegsiter4EditField = uieditfield(app.RegisterEditTab, 'numeric');
            app.ReadRegsiter4EditField.ValueDisplayFormat = '%.0f';
            app.ReadRegsiter4EditField.Editable = 'off';
            app.WriteModifiedRegsButton = uibutton(app.RegisterEditTab, 'push');
            app.WriteModifiedRegsButton.Text = 'Write Modified Regs';
            app.RereadAllButton = uibutton(app.RegisterEditTab, 'push');
            app.RereadAllButton.Text = 'Reread All';
            app.RButton = uibutton(app.RegisterEditTab, 'push');
            app.RButton.Text = 'R';
            app.RButton_2 = uibutton(app.RegisterEditTab, 'push');
            app.RButton_2.Text = 'R';
            app.RButton_3 = uibutton(app.RegisterEditTab, 'push');
            app.RButton_3.Text = 'R';
            app.RButton_4 = uibutton(app.RegisterEditTab, 'push');
            app.RButton_4.Text = 'R';
            app.RButton_5 = uibutton(app.RegisterEditTab, 'push');
            app.RButton_5.Text = 'R';
            app.RButton_6 = uibutton(app.RegisterEditTab, 'push');
            app.RButton_6.Text = 'R';
            app.RButton_7 = uibutton(app.RegisterEditTab, 'push');
            app.RButton_7.Text = 'R';
            app.RButton_8 = uibutton(app.RegisterEditTab, 'push');
            app.RButton_8.Text = 'R';
            app.RButton_9 = uibutton(app.RegisterEditTab, 'push');
            app.RButton_9.Text = 'R';
            app.ConfigureTab = uitab(app.TabGroup);
            app.ConfigureTab.Title = 'Configure';
            app.TabGroup2 = uitabgroup(app.ConfigureTab);
            app.RCV_COR1Tab = uitab(app.TabGroup2);
            app.RCV_COR1Tab.Title = 'RCV_COR1';
            app.GridLayout_2 = uigridlayout(app.RCV_COR1Tab);
            app.Label_8 = uilabel(app.GridLayout_2);
            app.Label_8.Text = '15:8 (8)';
            app.BitsLengthLabel_2 = uilabel(app.GridLayout_2);
            app.BitsLengthLabel_2.Text = 'Bit(s) (Length)';
            app.BinaryLabel_2 = uilabel(app.GridLayout_2);
            app.BinaryLabel_2.Text = 'Binary';
            app.DescriptionLabel_2 = uilabel(app.GridLayout_2);
            app.DescriptionLabel_2.Text = 'Description';
            app.Label_9 = uilabel(app.GridLayout_2);
            app.Label_9.Text = '7:0 (8)';
            app.bitcodefor8bitcorrelatorlowersectionforothersEditFieldLabel = uilabel(app.GridLayout_2);
            app.bitcodefor8bitcorrelatorlowersectionforothersEditFieldLabel.Text = '8-bit code for 8-bit correlator, lower section for others';
            app.LowerCorrelatorCodeEditField = uieditfield(app.GridLayout_2, 'text');
            app.LowerCorrelatorCodeEditField.Value = '1010';
            app.WriteButton_2 = uibutton(app.GridLayout_2, 'push');
            app.WriteButton_2.ButtonPushedFcn = createCallbackFcn(app, @WriteButton_2Pushed, true);
            app.WriteButton_2.Text = 'Write';
            app.DiscardChangesButton_2 = uibutton(app.GridLayout_2, 'push');
            app.DiscardChangesButton_2.Text = 'Discard Changes';
            app.UpperPartof15bitcodeforall15bitcorrelatorsEditFieldLabel = uilabel(app.GridLayout_2);
            app.UpperPartof15bitcodeforall15bitcorrelatorsEditFieldLabel.Text = 'Upper Part of 15 bit code for all 15-bit correlators';
            app.UpperCorrelatorCodeEditField = uieditfield(app.GridLayout_2, 'text');
            app.UpperCorrelatorCodeEditField.Value = '10100000';
            app.RCV_COR2Tab = uitab(app.TabGroup2);
            app.RCV_COR2Tab.Title = 'RCV_COR2';
            app.GridLayout_3 = uigridlayout(app.RCV_COR2Tab);
            app.Label_10 = uilabel(app.GridLayout_3);
            app.Label_10.Text = '14:0 (15)';
            app.BitsLengthLabel_3 = uilabel(app.GridLayout_3);
            app.BitsLengthLabel_3.Text = 'Bit(s) (Length)';
            app.BinaryLabel_3 = uilabel(app.GridLayout_3);
            app.BinaryLabel_3.Text = 'Binary';
            app.DescriptionLabel_3 = uilabel(app.GridLayout_3);
            app.DescriptionLabel_3.Text = 'Description';
            app.WriteButton_3 = uibutton(app.GridLayout_3, 'push');
            app.WriteButton_3.Text = 'Write';
            app.DiscardChangesButton_3 = uibutton(app.GridLayout_3, 'push');
            app.DiscardChangesButton_3.Text = 'Discard Changes';
            app.Upper15bitcodefor31bitcorrelatorEditFieldLabel = uilabel(app.GridLayout_3);
            app.Upper15bitcodefor31bitcorrelatorEditFieldLabel.Text = 'Upper 15 bit code for 31-bit correlator';
            app.Upper15bitcodefor31bitcorrelatorEditField = uieditfield(app.GridLayout_3, 'text');
            app.Upper15bitcodefor31bitcorrelatorEditField.Value = '10100000';
            app.RCV_AGOC1Tab = uitab(app.TabGroup2);
            app.RCV_AGOC1Tab.Title = 'RCV_AGOC1';
            app.GridLayout_4 = uigridlayout(app.RCV_AGOC1Tab);
            app.Label_11 = uilabel(app.GridLayout_4);
            app.Label_11.Text = '15:10 (6)';
            app.BitsLengthLabel_4 = uilabel(app.GridLayout_4);
            app.BitsLengthLabel_4.Text = 'Bit(s) (Length)';
            app.BinaryLabel_4 = uilabel(app.GridLayout_4);
            app.BinaryLabel_4.Text = 'Binary';
            app.DescriptionLabel_4 = uilabel(app.GridLayout_4);
            app.DescriptionLabel_4.Text = 'Description';
            app.Fine1sThresholdEditFieldLabel = uilabel(app.GridLayout_4);
            app.Fine1sThresholdEditFieldLabel.Text = 'Fine 1s Threshold';
            app.Fine1sThresholdEditField = uieditfield(app.GridLayout_4, 'text');
            app.Label_12 = uilabel(app.GridLayout_4);
            app.Label_12.Text = '9:0 (10)';
            app.WindowSizeEditFieldLabel = uilabel(app.GridLayout_4);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.GridLayout_4, 'text');
            app.WindowSizeEditField.Value = '1010';
            app.WriteButton_4 = uibutton(app.GridLayout_4, 'push');
            app.WriteButton_4.Text = 'Write';
            app.DiscardChangesButton_4 = uibutton(app.GridLayout_4, 'push');
            app.DiscardChangesButton_4.Text = 'Discard Changes';
            app.RCV_AGOC2Tab = uitab(app.TabGroup2);
            app.RCV_AGOC2Tab.Title = 'RCV_AGOC2';
            app.GridLayout_5 = uigridlayout(app.RCV_AGOC2Tab);
            app.BitsLengthLabel_5 = uilabel(app.GridLayout_5);
            app.BitsLengthLabel_5.Text = 'Bit(s) (Length)';
            app.BinaryLabel_5 = uilabel(app.GridLayout_5);
            app.BinaryLabel_5.Text = 'Binary';
            app.DescriptionLabel_5 = uilabel(app.GridLayout_5);
            app.DescriptionLabel_5.Text = 'Description';
            app.Label_14 = uilabel(app.GridLayout_5);
            app.Label_14.Text = '14:8 (7)';
            app.Sequential1sThresholdEditFieldLabel = uilabel(app.GridLayout_5);
            app.Sequential1sThresholdEditFieldLabel.Text = 'Sequential 1s Threshold';
            app.Sequential1sThresholdEditField = uieditfield(app.GridLayout_5, 'text');
            app.Sequential1sThresholdEditField.Value = '1010';
            app.Label_15 = uilabel(app.GridLayout_5);
            app.Label_15.Text = '7:0 (8)';
            app.Moderate1sThresholdEditFieldLabel = uilabel(app.GridLayout_5);
            app.Moderate1sThresholdEditFieldLabel.Text = 'Moderate 1s Threshold';
            app.Moderate1sThresholdEditField = uieditfield(app.GridLayout_5, 'text');
            app.Moderate1sThresholdEditField.Value = '001010';
            app.WriteButton_5 = uibutton(app.GridLayout_5, 'push');
            app.WriteButton_5.Text = 'Write';
            app.DiscardChangesButton_5 = uibutton(app.GridLayout_5, 'push');
            app.DiscardChangesButton_5.Text = 'Discard Changes';
            app.RCV_AGOC3Tab = uitab(app.TabGroup2);
            app.RCV_AGOC3Tab.Title = 'RCV_AGOC3';
            app.GridLayout_6 = uigridlayout(app.RCV_AGOC3Tab);
            app.Label_16 = uilabel(app.GridLayout_6);
            app.Label_16.Text = '14:10 (5)';
            app.BitsLengthLabel_6 = uilabel(app.GridLayout_6);
            app.BitsLengthLabel_6.Text = 'Bit(s) (Length)';
            app.BinaryLabel_6 = uilabel(app.GridLayout_6);
            app.BinaryLabel_6.Text = 'Binary';
            app.DescriptionLabel_6 = uilabel(app.GridLayout_6);
            app.DescriptionLabel_6.Text = 'Description';
            app.BoundaryFlagThresholdEditFieldLabel = uilabel(app.GridLayout_6);
            app.BoundaryFlagThresholdEditFieldLabel.Text = 'Boundary Flag Threshold';
            app.BoundaryFlagThresholdEditField = uieditfield(app.GridLayout_6, 'text');
            app.Label_17 = uilabel(app.GridLayout_6);
            app.Label_17.Text = '9:0 (10)';
            app.Sequential0sThresholdEditFieldLabel = uilabel(app.GridLayout_6);
            app.Sequential0sThresholdEditFieldLabel.Text = 'Sequential 0s Threshold';
            app.Sequential0sThresholdEditField = uieditfield(app.GridLayout_6, 'text');
            app.Sequential0sThresholdEditField.Value = '1010';
            app.WriteButton_6 = uibutton(app.GridLayout_6, 'push');
            app.WriteButton_6.Text = 'Write';
            app.DiscardChangesButton_6 = uibutton(app.GridLayout_6, 'push');
            app.DiscardChangesButton_6.Text = 'Discard Changes';
            app.RCV_AGOC4Tab = uitab(app.TabGroup2);
            app.RCV_AGOC4Tab.Title = 'RCV_AGOC4';
            app.GridLayout_7 = uigridlayout(app.RCV_AGOC4Tab);
            app.Label_18 = uilabel(app.GridLayout_7);
            app.Label_18.Text = '15:12 (4)';
            app.BitsLengthLabel_7 = uilabel(app.GridLayout_7);
            app.BitsLengthLabel_7.Text = 'Bit(s) (Length)';
            app.BinaryLabel_7 = uilabel(app.GridLayout_7);
            app.BinaryLabel_7.Text = 'Binary';
            app.DescriptionLabel_7 = uilabel(app.GridLayout_7);
            app.DescriptionLabel_7.Text = 'Description';
            app.MaxGainLimitEditFieldLabel = uilabel(app.GridLayout_7);
            app.MaxGainLimitEditFieldLabel.Text = 'Max Gain Limit';
            app.MaxGainLimitEditField = uieditfield(app.GridLayout_7, 'text');
            app.Label_19 = uilabel(app.GridLayout_7);
            app.Label_19.Text = '11:6 (6)';
            app.BottomComparatorThresholdEditFieldLabel = uilabel(app.GridLayout_7);
            app.BottomComparatorThresholdEditFieldLabel.Text = 'Bottom Comparator Threshold';
            app.BottomComparatorThresholdEditField = uieditfield(app.GridLayout_7, 'text');
            app.BottomComparatorThresholdEditField.Value = '1010';
            app.WriteButton_7 = uibutton(app.GridLayout_7, 'push');
            app.WriteButton_7.Text = 'Write';
            app.DiscardChangesButton_7 = uibutton(app.GridLayout_7, 'push');
            app.DiscardChangesButton_7.Text = 'Discard Changes';
            app.Label_20 = uilabel(app.GridLayout_7);
            app.Label_20.Text = '5:0 (6)';
            app.TopComparatorThresholdEditFieldLabel = uilabel(app.GridLayout_7);
            app.TopComparatorThresholdEditFieldLabel.Text = 'Top Comparator Threshold';
            app.TopComparatorThresholdEditField = uieditfield(app.GridLayout_7, 'text');
            app.TopComparatorThresholdEditField.Value = '1010';
            app.RCV_AGOC5Tab = uitab(app.TabGroup2);
            app.RCV_AGOC5Tab.Title = 'RCV_AGOC5';
            app.GridLayout = uigridlayout(app.RCV_AGOC5Tab);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '15:14';
            app.BitsLengthLabel = uilabel(app.GridLayout);
            app.BitsLengthLabel.Text = 'Bit(s) (Length)';
            app.BinaryLabel = uilabel(app.GridLayout);
            app.BinaryLabel.Text = 'Binary';
            app.DescriptionLabel = uilabel(app.GridLayout);
            app.DescriptionLabel.Text = 'Description';
            app.InaccessibleEditFieldLabel = uilabel(app.GridLayout);
            app.InaccessibleEditFieldLabel.Text = 'Inaccessible';
            app.InaccessibleEditField = uieditfield(app.GridLayout, 'text');
            app.InaccessibleEditField.Editable = 'off';
            app.InaccessibleEditField.Value = '-';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '13:10';
            app.GainResetEditFieldLabel = uilabel(app.GridLayout);
            app.GainResetEditFieldLabel.Text = 'Gain Reset';
            app.GainResetEditField = uieditfield(app.GridLayout, 'text');
            app.GainResetEditField.Value = '1010';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '9:4';
            app.OffsetResetEditFieldLabel = uilabel(app.GridLayout);
            app.OffsetResetEditFieldLabel.Text = 'Offset Reset';
            app.OffsetResetEditField = uieditfield(app.GridLayout, 'text');
            app.OffsetResetEditField.Value = '001010';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '3';
            app.GainMuxEditFieldLabel = uilabel(app.GridLayout);
            app.GainMuxEditFieldLabel.Text = 'Gain Mux';
            app.GainMuxEditField = uieditfield(app.GridLayout, 'text');
            app.GainMuxEditField.Value = '0';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '2';
            app.GainEnableEditFieldLabel = uilabel(app.GridLayout);
            app.GainEnableEditFieldLabel.Text = 'Gain Enable';
            app.GainEnableEditField = uieditfield(app.GridLayout, 'text');
            app.GainEnableEditField.Value = '1';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '1';
            app.OffsetEnableEditFieldLabel = uilabel(app.GridLayout);
            app.OffsetEnableEditFieldLabel.Text = 'Offset Enable';
            app.OffsetEnableEditField = uieditfield(app.GridLayout, 'text');
            app.OffsetEnableEditField.Value = '1';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '0';
            app.AGOCResetEditFieldLabel = uilabel(app.GridLayout);
            app.AGOCResetEditFieldLabel.Text = 'AGOC Reset';
            app.AGOCResetEditField = uieditfield(app.GridLayout, 'text');
            app.AGOCResetEditField.Value = '0';
            app.WriteButton = uibutton(app.GridLayout, 'push');
            app.WriteButton.Text = 'Write';
            app.DiscardChangesButton = uibutton(app.GridLayout, 'push');
            app.DiscardChangesButton.Text = 'Discard Changes';
            app.RCV_MISCTab = uitab(app.TabGroup2);
            app.RCV_MISCTab.Title = 'RCV_MISC';
            app.GridLayout_8 = uigridlayout(app.RCV_MISCTab);
            app.Label_21 = uilabel(app.GridLayout_8);
            app.Label_21.Text = '9:8 (2)';
            app.BitsLengthLabel_8 = uilabel(app.GridLayout_8);
            app.BitsLengthLabel_8.Text = 'Bit(s) (Length)';
            app.BinaryLabel_8 = uilabel(app.GridLayout_8);
            app.BinaryLabel_8.Text = 'Binary';
            app.DescriptionLabel_8 = uilabel(app.GridLayout_8);
            app.DescriptionLabel_8.Text = 'Description';
            app.cor_selEditFieldLabel = uilabel(app.GridLayout_8);
            app.cor_selEditFieldLabel.Text = 'cor_sel';
            app.cor_selEditField = uieditfield(app.GridLayout_8, 'text');
            app.Label_22 = uilabel(app.GridLayout_8);
            app.Label_22.Text = '7:6 (2)';
            app.AGOC_selEditFieldLabel = uilabel(app.GridLayout_8);
            app.AGOC_selEditFieldLabel.Text = 'AGOC_sel';
            app.AGOC_selEditField = uieditfield(app.GridLayout_8, 'text');
            app.AGOC_selEditField.Value = '1010';
            app.WriteButton_8 = uibutton(app.GridLayout_8, 'push');
            app.WriteButton_8.Text = 'Write';
            app.DiscardChangesButton_8 = uibutton(app.GridLayout_8, 'push');
            app.DiscardChangesButton_8.Text = 'Discard Changes';
            app.Label_23 = uilabel(app.GridLayout_8);
            app.Label_23.Text = '5 (1)';
            app.en_cor_selEditFieldLabel = uilabel(app.GridLayout_8);
            app.en_cor_selEditFieldLabel.Text = 'en_cor_sel';
            app.en_cor_selEditField = uieditfield(app.GridLayout_8, 'text');
            app.en_cor_selEditField.Value = '1010';
            app.Label_24 = uilabel(app.GridLayout_8);
            app.Label_24.Text = '4:0 (5)';
            app.CorrelatorErrorThreshold40for31bit30otherwiseEditFieldLabel = uilabel(app.GridLayout_8);
            app.CorrelatorErrorThreshold40for31bit30otherwiseEditFieldLabel.Text = 'Correlator Error Threshold 4:0 for 31 bit, 3:0 otherwise';
            app.CorrelatorErrorThreshold40for31bit30otherwiseEditField = uieditfield(app.GridLayout_8, 'text');
            app.CorrelatorErrorThreshold40for31bit30otherwiseEditField.Value = '1010';
            app.RCV_TMG1Tab = uitab(app.TabGroup2);
            app.RCV_TMG1Tab.Title = 'RCV_TMG1';
            app.GridLayout_9 = uigridlayout(app.RCV_TMG1Tab);
            app.Label_25 = uilabel(app.GridLayout_9);
            app.Label_25.Text = '12 (1)';
            app.BitsLengthLabel_9 = uilabel(app.GridLayout_9);
            app.BitsLengthLabel_9.Text = 'Bit(s) (Length)';
            app.BinaryLabel_9 = uilabel(app.GridLayout_9);
            app.BinaryLabel_9.Text = 'Binary';
            app.DescriptionLabel_9 = uilabel(app.GridLayout_9);
            app.DescriptionLabel_9.Text = 'Description';
            app.TimingResetEditFieldLabel = uilabel(app.GridLayout_9);
            app.TimingResetEditFieldLabel.Text = 'Timing Reset';
            app.TimingResetEditField = uieditfield(app.GridLayout_9, 'text');
            app.Label_26 = uilabel(app.GridLayout_9);
            app.Label_26.Text = '11:0 (12)';
            app.ShowClockValueEditFieldLabel = uilabel(app.GridLayout_9);
            app.ShowClockValueEditFieldLabel.Text = 'Show Clock Value';
            app.ShowClockValueEditField = uieditfield(app.GridLayout_9, 'text');
            app.ShowClockValueEditField.Value = '1010';
            app.WriteButton_9 = uibutton(app.GridLayout_9, 'push');
            app.WriteButton_9.Text = 'Write';
            app.DiscardChangesButton_9 = uibutton(app.GridLayout_9, 'push');
            app.DiscardChangesButton_9.Text = 'Discard Changes';
            app.RCV_TMG2Tab = uitab(app.TabGroup2);
            app.RCV_TMG2Tab.Title = 'RCV_TMG2';
            app.GridLayout_10 = uigridlayout(app.RCV_TMG2Tab);
            app.Label_27 = uilabel(app.GridLayout_10);
            app.Label_27.Text = '11:0 (12)';
            app.BitsLengthLabel_10 = uilabel(app.GridLayout_10);
            app.BitsLengthLabel_10.Text = 'Bit(s) (Length)';
            app.BinaryLabel_10 = uilabel(app.GridLayout_10);
            app.BinaryLabel_10.Text = 'Binary';
            app.DescriptionLabel_10 = uilabel(app.GridLayout_10);
            app.DescriptionLabel_10.Text = 'Description';
            app.AnalogActivateValueEditFieldLabel = uilabel(app.GridLayout_10);
            app.AnalogActivateValueEditFieldLabel.Text = 'Analog Activate Value';
            app.AnalogActivateValueEditField = uieditfield(app.GridLayout_10, 'text');
            app.WriteButton_10 = uibutton(app.GridLayout_10, 'push');
            app.WriteButton_10.Text = 'Write';
            app.DiscardChangesButton_10 = uibutton(app.GridLayout_10, 'push');
            app.DiscardChangesButton_10.Text = 'Discard Changes';
            app.RCV_TMG3Tab = uitab(app.TabGroup2);
            app.RCV_TMG3Tab.Title = 'RCV_TMG3';
            app.GridLayout_11 = uigridlayout(app.RCV_TMG3Tab);
            app.Label_28 = uilabel(app.GridLayout_11);
            app.Label_28.Text = '11:0 (12)';
            app.BitsLengthLabel_11 = uilabel(app.GridLayout_11);
            app.BitsLengthLabel_11.Text = 'Bit(s) (Length)';
            app.BinaryLabel_11 = uilabel(app.GridLayout_11);
            app.BinaryLabel_11.Text = 'Binary';
            app.DescriptionLabel_11 = uilabel(app.GridLayout_11);
            app.DescriptionLabel_11.Text = 'Description';
            app.AnalogOnValueEditFieldLabel = uilabel(app.GridLayout_11);
            app.AnalogOnValueEditFieldLabel.Text = 'Analog On Value';
            app.AnalogOnValueEditField = uieditfield(app.GridLayout_11, 'text');
            app.WriteButton_11 = uibutton(app.GridLayout_11, 'push');
            app.WriteButton_11.Text = 'Write';
            app.DiscardChangesButton_11 = uibutton(app.GridLayout_11, 'push');
            app.DiscardChangesButton_11.Text = 'Discard Changes';
            app.RCV_TMG4Tab = uitab(app.TabGroup2);
            app.RCV_TMG4Tab.Title = 'RCV_TMG4';
            app.GridLayout_12 = uigridlayout(app.RCV_TMG4Tab);
            app.Label_29 = uilabel(app.GridLayout_12);
            app.Label_29.Text = '11:0 (12)';
            app.BitsLengthLabel_12 = uilabel(app.GridLayout_12);
            app.BitsLengthLabel_12.Text = 'Bit(s) (Length)';
            app.BinaryLabel_12 = uilabel(app.GridLayout_12);
            app.BinaryLabel_12.Text = 'Binary';
            app.DescriptionLabel_12 = uilabel(app.GridLayout_12);
            app.DescriptionLabel_12.Text = 'Description';
            app.RFOnValueEditFieldLabel = uilabel(app.GridLayout_12);
            app.RFOnValueEditFieldLabel.Text = 'RF On Value';
            app.RFOnValueEditField = uieditfield(app.GridLayout_12, 'text');
            app.WriteButton_12 = uibutton(app.GridLayout_12, 'push');
            app.WriteButton_12.Text = 'Write';
            app.DiscardChangesButton_12 = uibutton(app.GridLayout_12, 'push');
            app.DiscardChangesButton_12.Text = 'Discard Changes';
            app.RCV_TMG5Tab = uitab(app.TabGroup2);
            app.RCV_TMG5Tab.Title = 'RCV_TMG5';
            app.GridLayout_13 = uigridlayout(app.RCV_TMG5Tab);
            app.Label_30 = uilabel(app.GridLayout_13);
            app.Label_30.Text = '11:0 (12)';
            app.BitsLengthLabel_13 = uilabel(app.GridLayout_13);
            app.BitsLengthLabel_13.Text = 'Bit(s) (Length)';
            app.BinaryLabel_13 = uilabel(app.GridLayout_13);
            app.BinaryLabel_13.Text = 'Binary';
            app.DescriptionLabel_13 = uilabel(app.GridLayout_13);
            app.DescriptionLabel_13.Text = 'Description';
            app.AnalogOffValueEditFieldLabel = uilabel(app.GridLayout_13);
            app.AnalogOffValueEditFieldLabel.Text = 'Analog Off Value';
            app.AnalogOffValueEditField = uieditfield(app.GridLayout_13, 'text');
            app.WriteButton_13 = uibutton(app.GridLayout_13, 'push');
            app.WriteButton_13.Text = 'Write';
            app.DiscardChangesButton_13 = uibutton(app.GridLayout_13, 'push');
            app.DiscardChangesButton_13.Text = 'Discard Changes';
            app.RCV_TMG6Tab = uitab(app.TabGroup2);
            app.RCV_TMG6Tab.Title = 'RCV_TMG6';
            app.GridLayout_14 = uigridlayout(app.RCV_TMG6Tab);
            app.Label_31 = uilabel(app.GridLayout_14);
            app.Label_31.Text = '11:0 (12)';
            app.BitsLengthLabel_14 = uilabel(app.GridLayout_14);
            app.BitsLengthLabel_14.Text = 'Bit(s) (Length)';
            app.BinaryLabel_14 = uilabel(app.GridLayout_14);
            app.BinaryLabel_14.Text = 'Binary';
            app.DescriptionLabel_14 = uilabel(app.GridLayout_14);
            app.DescriptionLabel_14.Text = 'Description';
            app.RFOffValueEditFieldLabel = uilabel(app.GridLayout_14);
            app.RFOffValueEditFieldLabel.Text = 'RF Off Value';
            app.RFOffValueEditField = uieditfield(app.GridLayout_14, 'text');
            app.WriteButton_14 = uibutton(app.GridLayout_14, 'push');
            app.WriteButton_14.Text = 'Write';
            app.DiscardChangesButton_14 = uibutton(app.GridLayout_14, 'push');
            app.DiscardChangesButton_14.Text = 'Discard Changes';
            app.RCV_CLK1Tab = uitab(app.TabGroup2);
            app.RCV_CLK1Tab.Title = 'RCV_CLK1';
            app.GridLayout_15 = uigridlayout(app.RCV_CLK1Tab);
            app.Label_32 = uilabel(app.GridLayout_15);
            app.Label_32.Text = '10:0 (11)';
            app.BitsLengthLabel_15 = uilabel(app.GridLayout_15);
            app.BitsLengthLabel_15.Text = 'Bit(s) (Length)';
            app.BinaryLabel_15 = uilabel(app.GridLayout_15);
            app.BinaryLabel_15.Text = 'Binary';
            app.DescriptionLabel_15 = uilabel(app.GridLayout_15);
            app.DescriptionLabel_15.Text = 'Description';
            app.csub_sel_10throughcsub_sel_0EditFieldLabel = uilabel(app.GridLayout_15);
            app.csub_sel_10throughcsub_sel_0EditFieldLabel.Text = 'csub_sel_10 through csub_sel_0';
            app.csub_sel_10throughcsub_sel_0EditField = uieditfield(app.GridLayout_15, 'text');
            app.csub_sel_10throughcsub_sel_0EditField.Value = '-';
            app.WriteButton_15 = uibutton(app.GridLayout_15, 'push');
            app.WriteButton_15.Text = 'Write';
            app.DiscardChangesButton_15 = uibutton(app.GridLayout_15, 'push');
            app.DiscardChangesButton_15.Text = 'Discard Changes';
            app.RCV_CLK2Tab = uitab(app.TabGroup2);
            app.RCV_CLK2Tab.Title = 'RCV_CLK2';
            app.GridLayout_16 = uigridlayout(app.RCV_CLK2Tab);
            app.Label_33 = uilabel(app.GridLayout_16);
            app.Label_33.Text = '11:0 (12)';
            app.BitsLengthLabel_16 = uilabel(app.GridLayout_16);
            app.BitsLengthLabel_16.Text = 'Bit(s) (Length)';
            app.BinaryLabel_16 = uilabel(app.GridLayout_16);
            app.BinaryLabel_16.Text = 'Binary';
            app.DescriptionLabel_16 = uilabel(app.GridLayout_16);
            app.DescriptionLabel_16.Text = 'Description';
            app.DIV_11throughDIV_0EditFieldLabel = uilabel(app.GridLayout_16);
            app.DIV_11throughDIV_0EditFieldLabel.Text = 'DIV_11 through DIV_0';
            app.DIV_11throughDIV_0EditField = uieditfield(app.GridLayout_16, 'text');
            app.WriteButton_16 = uibutton(app.GridLayout_16, 'push');
            app.WriteButton_16.Text = 'Write';
            app.DiscardChangesButton_16 = uibutton(app.GridLayout_16, 'push');
            app.DiscardChangesButton_16.Text = 'Discard Changes';
            app.RCV_CLK3Tab = uitab(app.TabGroup2);
            app.RCV_CLK3Tab.Title = 'RCV_CLK3';
            app.GridLayout_17 = uigridlayout(app.RCV_CLK3Tab);
            app.Label_34 = uilabel(app.GridLayout_17);
            app.Label_34.Text = '9:0 (10)';
            app.BitsLengthLabel_17 = uilabel(app.GridLayout_17);
            app.BitsLengthLabel_17.Text = 'Bit(s) (Length)';
            app.BinaryLabel_17 = uilabel(app.GridLayout_17);
            app.BinaryLabel_17.Text = 'Binary';
            app.DescriptionLabel_17 = uilabel(app.GridLayout_17);
            app.DescriptionLabel_17.Text = 'Description';
            app.HVT_SEL_0throughHVT_SEL_9EditFieldLabel = uilabel(app.GridLayout_17);
            app.HVT_SEL_0throughHVT_SEL_9EditFieldLabel.Text = 'HVT_SEL_0 through HVT_SEL_9';
            app.HVT_SEL_0throughHVT_SEL_9EditField = uieditfield(app.GridLayout_17, 'text');
            app.HVT_SEL_0throughHVT_SEL_9EditField.Value = '-';
            app.WriteButton_17 = uibutton(app.GridLayout_17, 'push');
            app.WriteButton_17.Text = 'Write';
            app.DiscardChangesButton_17 = uibutton(app.GridLayout_17, 'push');
            app.DiscardChangesButton_17.Text = 'Discard Changes';
            app.RCV_CLK4Tab = uitab(app.TabGroup2);
            app.RCV_CLK4Tab.Title = 'RCV_CLK4';
            app.GridLayout_18 = uigridlayout(app.RCV_CLK4Tab);
            app.Label_35 = uilabel(app.GridLayout_18);
            app.Label_35.Text = '15:14 (2)';
            app.BitsLengthLabel_18 = uilabel(app.GridLayout_18);
            app.BitsLengthLabel_18.Text = 'Bit(s) (Length)';
            app.BinaryLabel_18 = uilabel(app.GridLayout_18);
            app.BinaryLabel_18.Text = 'Binary';
            app.DescriptionLabel_18 = uilabel(app.GridLayout_18);
            app.DescriptionLabel_18.Text = 'Description';
            app.OUT_SEL_1OUT_SEL_2EditFieldLabel = uilabel(app.GridLayout_18);
            app.OUT_SEL_1OUT_SEL_2EditFieldLabel.Text = 'OUT_SEL_1, OUT_SEL_2';
            app.OUT_SEL_1OUT_SEL_2EditField = uieditfield(app.GridLayout_18, 'text');
            app.OUT_SEL_1OUT_SEL_2EditField.Value = '-';
            app.WriteButton_18 = uibutton(app.GridLayout_18, 'push');
            app.WriteButton_18.Text = 'Write';
            app.DiscardChangesButton_18 = uibutton(app.GridLayout_18, 'push');
            app.DiscardChangesButton_18.Text = 'Discard Changes';
            app.Label_36 = uilabel(app.GridLayout_18);
            app.Label_36.Text = '13 (1)';
            app.EXT_CLK_IN_SELEditFieldLabel = uilabel(app.GridLayout_18);
            app.EXT_CLK_IN_SELEditFieldLabel.Text = 'EXT_CLK_IN_SEL';
            app.EXT_CLK_IN_SELEditField = uieditfield(app.GridLayout_18, 'text');
            app.EXT_CLK_IN_SELEditField.Value = '-';
            app.Label_37 = uilabel(app.GridLayout_18);
            app.Label_37.Text = '12 (1)';
            app.BYPASS_PREDIVEditFieldLabel = uilabel(app.GridLayout_18);
            app.BYPASS_PREDIVEditFieldLabel.Text = 'BYPASS_PREDIV';
            app.BYPASS_PREDIVEditField = uieditfield(app.GridLayout_18, 'text');
            app.BYPASS_PREDIVEditField.Value = '-';
            app.Label_38 = uilabel(app.GridLayout_18);
            app.Label_38.Text = '11 (1)';
            app.RESET_DIVIDEREditFieldLabel = uilabel(app.GridLayout_18);
            app.RESET_DIVIDEREditFieldLabel.Text = 'RESET_DIVIDER';
            app.RESET_DIVIDEREditField = uieditfield(app.GridLayout_18, 'text');
            app.RESET_DIVIDEREditField.Value = '-';
            app.Label_39 = uilabel(app.GridLayout_18);
            app.Label_39.Text = '9:0 (10)';
            app.LVT_SEL_9throughLVT_SEL_0EditFieldLabel = uilabel(app.GridLayout_18);
            app.LVT_SEL_9throughLVT_SEL_0EditFieldLabel.Text = 'LVT_SEL_9 through LVT_SEL_0';
            app.LVT_SEL_9throughLVT_SEL_0EditField = uieditfield(app.GridLayout_18, 'text');
            app.LVT_SEL_9throughLVT_SEL_0EditField.Value = '-';
            app.RCV_CLK5Tab = uitab(app.TabGroup2);
            app.RCV_CLK5Tab.Title = 'RCV_CLK5';
            app.GridLayout_19 = uigridlayout(app.RCV_CLK5Tab);
            app.Label_40 = uilabel(app.GridLayout_19);
            app.Label_40.Text = '15:6 (10)';
            app.BitsLengthLabel_19 = uilabel(app.GridLayout_19);
            app.BitsLengthLabel_19.Text = 'Bit(s) (Length)';
            app.BinaryLabel_19 = uilabel(app.GridLayout_19);
            app.BinaryLabel_19.Text = 'Binary';
            app.DescriptionLabel_19 = uilabel(app.GridLayout_19);
            app.DescriptionLabel_19.Text = 'Description';
            app.RVT_SEL_9throughRVT_SEL_0EditFieldLabel = uilabel(app.GridLayout_19);
            app.RVT_SEL_9throughRVT_SEL_0EditFieldLabel.Text = 'RVT_SEL_9 through RVT_SEL_0';
            app.RVT_SEL_9throughRVT_SEL_0EditField = uieditfield(app.GridLayout_19, 'text');
            app.RVT_SEL_9throughRVT_SEL_0EditField.Value = '-';
            app.WriteButton_19 = uibutton(app.GridLayout_19, 'push');
            app.WriteButton_19.Text = 'Write';
            app.DiscardChangesButton_19 = uibutton(app.GridLayout_19, 'push');
            app.DiscardChangesButton_19.Text = 'Discard Changes';
            app.Label_41 = uilabel(app.GridLayout_19);
            app.Label_41.Text = '5 (1)';
            app.VREF2_SELEditFieldLabel = uilabel(app.GridLayout_19);
            app.VREF2_SELEditFieldLabel.Text = 'VREF2_SEL';
            app.VREF2_SELEditField = uieditfield(app.GridLayout_19, 'text');
            app.VREF2_SELEditField.Value = '-';
            app.Label_42 = uilabel(app.GridLayout_19);
            app.Label_42.Text = '4 (1)';
            app.VREF1_SELEditFieldLabel = uilabel(app.GridLayout_19);
            app.VREF1_SELEditFieldLabel.Text = 'VREF1_SEL';
            app.VREF1_SELEditField = uieditfield(app.GridLayout_19, 'text');
            app.VREF1_SELEditField.Value = '-';
            app.RCV_BBTab = uitab(app.TabGroup2);
            app.RCV_BBTab.Title = 'RCV_BB';
            app.GridLayout_20 = uigridlayout(app.RCV_BBTab);
            app.Label_43 = uilabel(app.GridLayout_20);
            app.Label_43.Text = '9:6 (4)';
            app.BitsLengthLabel_20 = uilabel(app.GridLayout_20);
            app.BitsLengthLabel_20.Text = 'Bit(s) (Length)';
            app.BinaryLabel_20 = uilabel(app.GridLayout_20);
            app.BinaryLabel_20.Text = 'Binary';
            app.DescriptionLabel_20 = uilabel(app.GridLayout_20);
            app.DescriptionLabel_20.Text = 'Description';
            app.Gain_SPIEditFieldLabel = uilabel(app.GridLayout_20);
            app.Gain_SPIEditFieldLabel.Text = 'Gain_SPI';
            app.Gain_SPIEditField = uieditfield(app.GridLayout_20, 'text');
            app.Gain_SPIEditField.Value = '-';
            app.WriteButton_20 = uibutton(app.GridLayout_20, 'push');
            app.WriteButton_20.Text = 'Write';
            app.DiscardChangesButton_20 = uibutton(app.GridLayout_20, 'push');
            app.DiscardChangesButton_20.Text = 'Discard Changes';
            app.Label_44 = uilabel(app.GridLayout_20);
            app.Label_44.Text = '5:0 (6)';
            app.Offset_SPIEditFieldLabel = uilabel(app.GridLayout_20);
            app.Offset_SPIEditFieldLabel.Text = 'Offset_SPI';
            app.Offset_SPIEditField = uieditfield(app.GridLayout_20, 'text');
            app.Offset_SPIEditField.Value = '-';
            app.RCV_RF1Tab = uitab(app.TabGroup2);
            app.RCV_RF1Tab.Title = 'RCV_RF1';
            app.GridLayout_21 = uigridlayout(app.RCV_RF1Tab);
            app.Label_45 = uilabel(app.GridLayout_21);
            app.Label_45.Text = '13 (1)';
            app.BitsLengthLabel_21 = uilabel(app.GridLayout_21);
            app.BitsLengthLabel_21.Text = 'Bit(s) (Length)';
            app.BinaryLabel_21 = uilabel(app.GridLayout_21);
            app.BinaryLabel_21.Text = 'Binary';
            app.DescriptionLabel_21 = uilabel(app.GridLayout_21);
            app.DescriptionLabel_21.Text = 'Description';
            app.ComparatorPadMuxEditFieldLabel = uilabel(app.GridLayout_21);
            app.ComparatorPadMuxEditFieldLabel.Text = 'Comparator Pad Mux';
            app.ComparatorPadMuxEditField = uieditfield(app.GridLayout_21, 'text');
            app.ComparatorPadMuxEditField.Value = '-';
            app.WriteButton_21 = uibutton(app.GridLayout_21, 'push');
            app.WriteButton_21.Text = 'Write';
            app.DiscardChangesButton_21 = uibutton(app.GridLayout_21, 'push');
            app.DiscardChangesButton_21.Text = 'Discard Changes';
            app.Label_46 = uilabel(app.GridLayout_21);
            app.Label_46.Text = '12 (1)';
            app.TimingPadMuxEditFieldLabel = uilabel(app.GridLayout_21);
            app.TimingPadMuxEditFieldLabel.Text = 'Timing Pad Mux';
            app.TimingPadMuxEditField = uieditfield(app.GridLayout_21, 'text');
            app.TimingPadMuxEditField.Value = '-';
            app.Label_47 = uilabel(app.GridLayout_21);
            app.Label_47.Text = '11 (1)';
            app.VCD0EditFieldLabel = uilabel(app.GridLayout_21);
            app.VCD0EditFieldLabel.Text = 'VCD0';
            app.VCD0EditField = uieditfield(app.GridLayout_21, 'text');
            app.VCD0EditField.Value = '-';
            app.Label_48 = uilabel(app.GridLayout_21);
            app.Label_48.Text = '10 (1)';
            app.VCD1EditFieldLabel = uilabel(app.GridLayout_21);
            app.VCD1EditFieldLabel.Text = 'VCD1';
            app.VCD1EditField = uieditfield(app.GridLayout_21, 'text');
            app.VCD1EditField.Value = '-';
            app.Label_49 = uilabel(app.GridLayout_21);
            app.Label_49.Text = '9 (1)';
            app.VCD2EditFieldLabel = uilabel(app.GridLayout_21);
            app.VCD2EditFieldLabel.Text = 'VCD2';
            app.VCD2EditField = uieditfield(app.GridLayout_21, 'text');
            app.VCD2EditField.Value = '-';
            app.Label_50 = uilabel(app.GridLayout_21);
            app.Label_50.Text = '8 (1)';
            app.LPF_ENEditFieldLabel = uilabel(app.GridLayout_21);
            app.LPF_ENEditFieldLabel.Text = 'LPF_EN';
            app.LPF_ENEditField = uieditfield(app.GridLayout_21, 'text');
            app.LPF_ENEditField.Value = '-';
            app.Label_51 = uilabel(app.GridLayout_21);
            app.Label_51.Text = '7 (1)';
            app.BB_IN_ENEditFieldLabel = uilabel(app.GridLayout_21);
            app.BB_IN_ENEditFieldLabel.Text = 'BB_IN_EN';
            app.BB_IN_ENEditField = uieditfield(app.GridLayout_21, 'text');
            app.BB_IN_ENEditField.Value = '-';
            app.Label_52 = uilabel(app.GridLayout_21);
            app.Label_52.Text = '6 (1)';
            app.BB_OUT_ENEditFieldLabel = uilabel(app.GridLayout_21);
            app.BB_OUT_ENEditFieldLabel.Text = 'BB_OUT_EN';
            app.BB_OUT_ENEditField = uieditfield(app.GridLayout_21, 'text');
            app.BB_OUT_ENEditField.Value = '-';
            app.Label_53 = uilabel(app.GridLayout_21);
            app.Label_53.Text = '5:0 (6)';
            app.CAP_5throughCAP_0EditFieldLabel = uilabel(app.GridLayout_21);
            app.CAP_5throughCAP_0EditFieldLabel.Text = 'CAP_5 through CAP_0';
            app.CAP_5throughCAP_0EditField = uieditfield(app.GridLayout_21, 'text');
            app.CAP_5throughCAP_0EditField.Value = '-';
            app.RCV_RF2Tab = uitab(app.TabGroup2);
            app.RCV_RF2Tab.Title = 'RCV_RF2';
            app.GridLayout_22 = uigridlayout(app.RCV_RF2Tab);
            app.Label_54 = uilabel(app.GridLayout_22);
            app.Label_54.Text = '15:0 (16)';
            app.BitsLengthLabel_22 = uilabel(app.GridLayout_22);
            app.BitsLengthLabel_22.Text = 'Bit(s) (Length)';
            app.BinaryLabel_22 = uilabel(app.GridLayout_22);
            app.BinaryLabel_22.Text = 'Binary';
            app.DescriptionLabel_22 = uilabel(app.GridLayout_22);
            app.DescriptionLabel_22.Text = 'Description';
            app.WriteButton_22 = uibutton(app.GridLayout_22, 'push');
            app.WriteButton_22.Text = 'Write';
            app.DiscardChangesButton_22 = uibutton(app.GridLayout_22, 'push');
            app.DiscardChangesButton_22.Text = 'Discard Changes';
            app.FLOATINGEditFieldLabel = uilabel(app.GridLayout_22);
            app.FLOATINGEditFieldLabel.Text = 'FLOATING';
            app.FLOATINGEditField = uieditfield(app.GridLayout_22, 'text');
            app.FLOATINGEditField.Value = '10100000';
            app.WRT_BBTab = uitab(app.TabGroup2);
            app.WRT_BBTab.Title = 'WRT_BB';
            app.GridLayout_23 = uigridlayout(app.WRT_BBTab);
            app.Label_55 = uilabel(app.GridLayout_23);
            app.Label_55.Text = '15:0 (16)';
            app.BitsLengthLabel_23 = uilabel(app.GridLayout_23);
            app.BitsLengthLabel_23.Text = 'Bit(s) (Length)';
            app.BinaryLabel_23 = uilabel(app.GridLayout_23);
            app.BinaryLabel_23.Text = 'Binary';
            app.DescriptionLabel_23 = uilabel(app.GridLayout_23);
            app.DescriptionLabel_23.Text = 'Description';
            app.WriteButton_23 = uibutton(app.GridLayout_23, 'push');
            app.WriteButton_23.Text = 'Write';
            app.DiscardChangesButton_23 = uibutton(app.GridLayout_23, 'push');
            app.DiscardChangesButton_23.Text = 'Discard Changes';
            app.FLOATINGEditField_2Label = uilabel(app.GridLayout_23);
            app.FLOATINGEditField_2Label.Text = 'FLOATING';
            app.FLOATINGEditField_2 = uieditfield(app.GridLayout_23, 'text');
            app.FLOATINGEditField_2.Value = '10100000';
            app.WRT_RF1Tab = uitab(app.TabGroup2);
            app.WRT_RF1Tab.Title = 'WRT_RF1';
            app.GridLayout_25 = uigridlayout(app.WRT_RF1Tab);
            app.Label_57 = uilabel(app.GridLayout_25);
            app.Label_57.Text = '13 (1)';
            app.BitsLengthLabel_25 = uilabel(app.GridLayout_25);
            app.BitsLengthLabel_25.Text = 'Bit(s) (Length)';
            app.BinaryLabel_25 = uilabel(app.GridLayout_25);
            app.BinaryLabel_25.Text = 'Binary';
            app.DescriptionLabel_25 = uilabel(app.GridLayout_25);
            app.DescriptionLabel_25.Text = 'Description';
            app.ComparatorPadMuxEditField_2Label = uilabel(app.GridLayout_25);
            app.ComparatorPadMuxEditField_2Label.Text = 'Comparator Pad Mux';
            app.ComparatorPadMuxEditField_2 = uieditfield(app.GridLayout_25, 'text');
            app.ComparatorPadMuxEditField_2.Value = '-';
            app.WriteButton_25 = uibutton(app.GridLayout_25, 'push');
            app.WriteButton_25.Text = 'Write';
            app.DiscardChangesButton_25 = uibutton(app.GridLayout_25, 'push');
            app.DiscardChangesButton_25.Text = 'Discard Changes';
            app.Label_58 = uilabel(app.GridLayout_25);
            app.Label_58.Text = '12 (1)';
            app.TimingPadMuxEditField_2Label = uilabel(app.GridLayout_25);
            app.TimingPadMuxEditField_2Label.Text = 'Timing Pad Mux';
            app.TimingPadMuxEditField_2 = uieditfield(app.GridLayout_25, 'text');
            app.TimingPadMuxEditField_2.Value = '-';
            app.Label_59 = uilabel(app.GridLayout_25);
            app.Label_59.Text = '11 (1)';
            app.VCD0EditField_2Label = uilabel(app.GridLayout_25);
            app.VCD0EditField_2Label.Text = 'VCD0';
            app.VCD0EditField_2 = uieditfield(app.GridLayout_25, 'text');
            app.VCD0EditField_2.Value = '-';
            app.Label_60 = uilabel(app.GridLayout_25);
            app.Label_60.Text = '10 (1)';
            app.VCD1EditField_2Label = uilabel(app.GridLayout_25);
            app.VCD1EditField_2Label.Text = 'VCD1';
            app.VCD1EditField_2 = uieditfield(app.GridLayout_25, 'text');
            app.VCD1EditField_2.Value = '-';
            app.Label_61 = uilabel(app.GridLayout_25);
            app.Label_61.Text = '9 (1)';
            app.VCD2EditField_2Label = uilabel(app.GridLayout_25);
            app.VCD2EditField_2Label.Text = 'VCD2';
            app.VCD2EditField_2 = uieditfield(app.GridLayout_25, 'text');
            app.VCD2EditField_2.Value = '-';
            app.Label_62 = uilabel(app.GridLayout_25);
            app.Label_62.Text = '8 (1)';
            app.LPF_ENEditField_2Label = uilabel(app.GridLayout_25);
            app.LPF_ENEditField_2Label.Text = 'LPF_EN';
            app.LPF_ENEditField_2 = uieditfield(app.GridLayout_25, 'text');
            app.LPF_ENEditField_2.Value = '-';
            app.Label_63 = uilabel(app.GridLayout_25);
            app.Label_63.Text = '7 (1)';
            app.BB_IN_ENEditField_2Label = uilabel(app.GridLayout_25);
            app.BB_IN_ENEditField_2Label.Text = 'BB_IN_EN';
            app.BB_IN_ENEditField_2 = uieditfield(app.GridLayout_25, 'text');
            app.BB_IN_ENEditField_2.Value = '-';
            app.Label_64 = uilabel(app.GridLayout_25);
            app.Label_64.Text = '6 (1)';
            app.BB_OUT_ENEditField_2Label = uilabel(app.GridLayout_25);
            app.BB_OUT_ENEditField_2Label.Text = 'BB_OUT_EN';
            app.BB_OUT_ENEditField_2 = uieditfield(app.GridLayout_25, 'text');
            app.BB_OUT_ENEditField_2.Value = '-';
            app.Label_65 = uilabel(app.GridLayout_25);
            app.Label_65.Text = '5:0 (6)';
            app.CAP_5throughCAP_0EditField_2Label = uilabel(app.GridLayout_25);
            app.CAP_5throughCAP_0EditField_2Label.Text = 'CAP_5 through CAP_0';
            app.CAP_5throughCAP_0EditField_2 = uieditfield(app.GridLayout_25, 'text');
            app.CAP_5throughCAP_0EditField_2.Value = '-';
            app.WRT_RF2Tab = uitab(app.TabGroup2);
            app.WRT_RF2Tab.Title = 'WRT_RF2';
            app.GridLayout_24 = uigridlayout(app.WRT_RF2Tab);
            app.Label_56 = uilabel(app.GridLayout_24);
            app.Label_56.Text = '15:0 (16)';
            app.BitsLengthLabel_24 = uilabel(app.GridLayout_24);
            app.BitsLengthLabel_24.Text = 'Bit(s) (Length)';
            app.BinaryLabel_24 = uilabel(app.GridLayout_24);
            app.BinaryLabel_24.Text = 'Binary';
            app.DescriptionLabel_24 = uilabel(app.GridLayout_24);
            app.DescriptionLabel_24.Text = 'Description';
            app.WriteButton_24 = uibutton(app.GridLayout_24, 'push');
            app.WriteButton_24.Text = 'Write';
            app.DiscardChangesButton_24 = uibutton(app.GridLayout_24, 'push');
            app.DiscardChangesButton_24.Text = 'Discard Changes';
            app.FLOATINGEditField_3Label = uilabel(app.GridLayout_24);
            app.FLOATINGEditField_3Label.Text = 'FLOATING';
            app.FLOATINGEditField_3 = uieditfield(app.GridLayout_24, 'text');
            app.FLOATINGEditField_3.Value = '10100000';
            app.WRT_AGOCTab = uitab(app.TabGroup2);
            app.WRT_AGOCTab.Title = 'WRT_AGOC';
            app.GridLayout_26 = uigridlayout(app.WRT_AGOCTab);
            app.Label_66 = uilabel(app.GridLayout_26);
            app.Label_66.Text = '15 (1)';
            app.BitsLengthLabel_26 = uilabel(app.GridLayout_26);
            app.BitsLengthLabel_26.Text = 'Bit(s) (Length)';
            app.BinaryLabel_26 = uilabel(app.GridLayout_26);
            app.BinaryLabel_26.Text = 'Binary';
            app.DescriptionLabel_26 = uilabel(app.GridLayout_26);
            app.DescriptionLabel_26.Text = 'Description';
            app.BoundaryFlagEditFieldLabel = uilabel(app.GridLayout_26);
            app.BoundaryFlagEditFieldLabel.Text = 'Boundary Flag';
            app.BoundaryFlagEditField = uieditfield(app.GridLayout_26, 'text');
            app.BoundaryFlagEditField.Value = '-';
            app.WriteButton_26 = uibutton(app.GridLayout_26, 'push');
            app.WriteButton_26.Text = 'Write';
            app.DiscardChangesButton_26 = uibutton(app.GridLayout_26, 'push');
            app.DiscardChangesButton_26.Text = 'Discard Changes';
            app.Label_67 = uilabel(app.GridLayout_26);
            app.Label_67.Text = '14 (1)';
            app.BottomComparatorInEditFieldLabel = uilabel(app.GridLayout_26);
            app.BottomComparatorInEditFieldLabel.Text = 'Bottom Comparator In';
            app.BottomComparatorInEditField = uieditfield(app.GridLayout_26, 'text');
            app.BottomComparatorInEditField.Value = '-';
            app.Label_68 = uilabel(app.GridLayout_26);
            app.Label_68.Text = '13 (1)';
            app.TopComparatorInEditFieldLabel = uilabel(app.GridLayout_26);
            app.TopComparatorInEditFieldLabel.Text = 'Top Comparator In';
            app.TopComparatorInEditField = uieditfield(app.GridLayout_26, 'text');
            app.TopComparatorInEditField.Value = '-';
            app.Label_69 = uilabel(app.GridLayout_26);
            app.Label_69.Text = '11:6 (6)';
            app.BottomComparatorOffsetEditFieldLabel = uilabel(app.GridLayout_26);
            app.BottomComparatorOffsetEditFieldLabel.Text = 'Bottom Comparator Offset';
            app.BottomComparatorOffsetEditField = uieditfield(app.GridLayout_26, 'text');
            app.BottomComparatorOffsetEditField.Value = '-';
            app.Label_70 = uilabel(app.GridLayout_26);
            app.Label_70.Text = '5:0 (6)';
            app.TopComparatorOffsetEditFieldLabel = uilabel(app.GridLayout_26);
            app.TopComparatorOffsetEditFieldLabel.Text = 'Top Comparator Offset';
            app.TopComparatorOffsetEditField = uieditfield(app.GridLayout_26, 'text');
            app.TopComparatorOffsetEditField.Value = '-';
            app.WRT_COR1Tab = uitab(app.TabGroup2);
            app.WRT_COR1Tab.Title = 'WRT_COR1';
            app.GridLayout_27 = uigridlayout(app.WRT_COR1Tab);
            app.Label_71 = uilabel(app.GridLayout_27);
            app.Label_71.Text = '15:12 (4)';
            app.BitsLengthLabel_27 = uilabel(app.GridLayout_27);
            app.BitsLengthLabel_27.Text = 'Bit(s) (Length)';
            app.BinaryLabel_27 = uilabel(app.GridLayout_27);
            app.BinaryLabel_27.Text = 'Binary';
            app.DescriptionLabel_27 = uilabel(app.GridLayout_27);
            app.DescriptionLabel_27.Text = 'Description';
            app.Error1_15b_osx2EditFieldLabel = uilabel(app.GridLayout_27);
            app.Error1_15b_osx2EditFieldLabel.Text = 'Error1_15b_osx2';
            app.Error1_15b_osx2EditField = uieditfield(app.GridLayout_27, 'text');
            app.Error1_15b_osx2EditField.Value = '-';
            app.WriteButton_27 = uibutton(app.GridLayout_27, 'push');
            app.WriteButton_27.Text = 'Write';
            app.DiscardChangesButton_27 = uibutton(app.GridLayout_27, 'push');
            app.DiscardChangesButton_27.Text = 'Discard Changes';
            app.Label_72 = uilabel(app.GridLayout_27);
            app.Label_72.Text = '11:8 (4)';
            app.Error0_15b_osx2EditFieldLabel = uilabel(app.GridLayout_27);
            app.Error0_15b_osx2EditFieldLabel.Text = 'Error0_15b_osx2';
            app.Error0_15b_osx2EditField = uieditfield(app.GridLayout_27, 'text');
            app.Error0_15b_osx2EditField.Value = '-';
            app.Label_73 = uilabel(app.GridLayout_27);
            app.Label_73.Text = '7:4 (4)';
            app.Error1_8bEditFieldLabel = uilabel(app.GridLayout_27);
            app.Error1_8bEditFieldLabel.Text = 'Error1_8b';
            app.Error1_8bEditField = uieditfield(app.GridLayout_27, 'text');
            app.Error1_8bEditField.Value = '-';
            app.Label_74 = uilabel(app.GridLayout_27);
            app.Label_74.Text = '3:0 (4)';
            app.Error0_8bEditFieldLabel = uilabel(app.GridLayout_27);
            app.Error0_8bEditFieldLabel.Text = 'Error0_8b';
            app.Error0_8bEditField = uieditfield(app.GridLayout_27, 'text');
            app.Error0_8bEditField.Value = '-';
            app.WRT_COR2Tab = uitab(app.TabGroup2);
            app.WRT_COR2Tab.Title = 'WRT_COR2';
            app.GridLayout_28 = uigridlayout(app.WRT_COR2Tab);
            app.Label_75 = uilabel(app.GridLayout_28);
            app.Label_75.Text = '15 (1)';
            app.BitsLengthLabel_28 = uilabel(app.GridLayout_28);
            app.BitsLengthLabel_28.Text = 'Bit(s) (Length)';
            app.BinaryLabel_28 = uilabel(app.GridLayout_28);
            app.BinaryLabel_28.Text = 'Binary';
            app.DescriptionLabel_28 = uilabel(app.GridLayout_28);
            app.DescriptionLabel_28.Text = 'Description';
            app.EditFieldLabel = uilabel(app.GridLayout_28);
            app.EditFieldLabel.Text = '0';
            app.EditField = uieditfield(app.GridLayout_28, 'text');
            app.EditField.Value = '-';
            app.WriteButton_28 = uibutton(app.GridLayout_28, 'push');
            app.WriteButton_28.Text = 'Write';
            app.DiscardChangesButton_28 = uibutton(app.GridLayout_28, 'push');
            app.DiscardChangesButton_28.Text = 'Discard Changes';
            app.Label_76 = uilabel(app.GridLayout_28);
            app.Label_76.Text = '14 (1)';
            app.EditField_2Label = uilabel(app.GridLayout_28);
            app.EditField_2Label.Text = '0';
            app.EditField_2 = uieditfield(app.GridLayout_28, 'text');
            app.EditField_2.Value = '-';
            app.Label_77 = uilabel(app.GridLayout_28);
            app.Label_77.Text = '13:9 (5)';
            app.Error1_31bEditFieldLabel = uilabel(app.GridLayout_28);
            app.Error1_31bEditFieldLabel.Text = 'Error1_31b';
            app.Error1_31bEditField = uieditfield(app.GridLayout_28, 'text');
            app.Error1_31bEditField.Value = '-';
            app.Label_78 = uilabel(app.GridLayout_28);
            app.Label_78.Text = '8:4 (5)';
            app.Error0_31bEditFieldLabel = uilabel(app.GridLayout_28);
            app.Error0_31bEditFieldLabel.Text = 'Error0_31b';
            app.Error0_31bEditField = uieditfield(app.GridLayout_28, 'text');
            app.Error0_31bEditField.Value = '-';
            app.Label_79 = uilabel(app.GridLayout_28);
            app.Label_79.Text = '3:0 (4)';
            app.Error0_15b_osx1EditFieldLabel = uilabel(app.GridLayout_28);
            app.Error0_15b_osx1EditFieldLabel.Text = 'Error0_15b_osx1';
            app.Error0_15b_osx1EditField = uieditfield(app.GridLayout_28, 'text');
            app.Error0_15b_osx1EditField.Value = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SamplifierGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end